classdef icalPR670 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveCSVMenu                  matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        ActionsMenu                  matlab.ui.container.Menu
        InitializeMenu               matlab.ui.container.Menu
        MeasureMenu                  matlab.ui.container.Menu
        PlotMenu                     matlab.ui.container.Menu
        EnterremoteMenu              matlab.ui.container.Menu
        QuitremoteMenu               matlab.ui.container.Menu
        FlywheelMenu                 matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutFlywheelMenu            matlab.ui.container.Menu
        AboutPTBMenu                 matlab.ui.container.Menu
        FileInformationPanel         matlab.ui.container.Panel
        CommentTextAreaLabel         matlab.ui.control.Label
        CommentTextArea              matlab.ui.control.TextArea
        FilenameEditFieldLabel       matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        DirectoryEditFieldLabel      matlab.ui.control.Label
        DirectoryEditField           matlab.ui.control.EditField
        icalPR670Label               matlab.ui.control.Label
        WavelengthnmPanel            matlab.ui.container.Panel
        StartEditFieldLabel          matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StepEditFieldLabel           matlab.ui.control.Label
        StepEditField                matlab.ui.control.NumericEditField
        StopEditFieldLabel           matlab.ui.control.Label
        StopEditField                matlab.ui.control.NumericEditField
        SpectroradiometerPR670Panel  matlab.ui.container.Panel
        MeasureSaveButton            matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        InitializeButton             matlab.ui.control.Button
        RepeatsSpinnerLabel          matlab.ui.control.Label
        RepeatsSpinner               matlab.ui.control.Spinner
        ReadyLampLabel               matlab.ui.control.Label
        ReadyLamp                    matlab.ui.control.Lamp
        spdGraph                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function wave = prGetWave(app)
            % Returns the vector of wavelengths
            start = app.StartEditField.Value;
            step  = app.StepEditField.Value;
            stop  = app.StopEditField.Value;
            wave  = start:step:stop;
        end
        
        function icalPlot(app)
            % Plot the UserData slot.  Maybe we should have a different
            % slot
            wave = prGetWave(app);
            spd  = app.spdGraph.UserData;
            
            % Do the plot
            plot(app.spdGraph,wave,spd);
            fullName = icalFilename(app);
            fname = app.FilenameEditField.Value;
            app.spdGraph.Title.String = sprintf('%s (%s)',datestr(now),fname);
            grid(app.spdGraph,'on');
        end
        
        function fullName = icalSave(app,varargin)
            % Save the data from the spd in a txt or mat file.
            p = inputParser;
            p.addRequired('app');
            vFunc = @(x)(ismember(x,{'txt','json','mat','csv'}));
            p.addParameter('extension','mat',vFunc)
            p.parse(app,varargin{:});
            ext = p.Results.extension;
            
            wave = prGetWave(app);
            spd = app.spdGraph.UserData;
            
            fullName = icalFilename(app,'extension',ext);
            
            comment = app.CommentTextArea.Value;
            if exist(fullName,'file')
                % Include the desired Default answer
                answer = questdlg('Overwrite?','File save','Yes','No','No');
                if strcmp('No',answer), disp('Not saved'); return; end
            end
            switch ext
                case 'mat'
                    % Save raw data as an ISETCam spectral file
                    ieSaveSpectralFile(wave,spd,comment,fullName);
                case {'txt','csv'}
                    % Save as CSV text file for Flywheel upload (probably)
                    writematrix([wave(:),spd(:)],fullName);
                otherwise
                    error('Extension not managed %s\n',ext);
            end
        end
        
        function fullName = icalFilename(app,varargin)
            % Create a file name with some extension.  By default, .mat
            % extension
            p = inputParser;
            p.addRequired('app');
            vFunc = @(x)(ismember(x,{'txt','json','mat','csv'}));
            p.addParameter('extension','mat',vFunc)
            p.parse(app,varargin{:});
            ext = p.Results.extension;
            
            directory = app.DirectoryEditField.Value;
            if isempty(directory)
                % By default the directory is in local and with the date.
                tmp = datestr(now,'dd-mmm-yyyy');
                directory = fullfile(icalRootPath,'local',tmp);
                if ~exist(directory,'dir'), mkdir(directory); end
            end
            
            fname = app.FilenameEditField.Value;
            if isempty(fname)
                tmp = datestr(now,'dd-mmm-yyyy-HH-MM-SS');
                fname = sprintf('spd-%s',tmp);
            end
            
            fullName = fullfile(directory,fname);
            [p,n,e] = fileparts(fullName);
            
            if ~isequal(e,ext)
                fullName = fullfile(p,[n,'.',ext]);
            end
        end
        
    end
    
    methods (Access = public)
        % Maybe some of the functions about should be public?
        
        function  icalQuit(~)
            % Exits remote mode
            PR670write('Q', 0);
        end
        function icalStart(~)
            % Enters remote mode
            PR670write('PHOTO', 0);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MeasureSaveButton
        function btnMeasure(app, event)
            wave = prGetWave(app);
            
            app.ReadyLampLabel.Text = 'Measuring';
            app.ReadyLamp.Color = [1 0 0];
            
            spd    = PR670measspd([wave(1) wave(2)-wave(1) length(wave)]);
            app.spdGraph.UserData = spd;
            
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0 1 0];
            
            % Normally, we plot after measuring
            icalPlot(app);
            
            % Save it to a file
            fname = icalSave(app);
            fprintf('SPD saved in %s\n',fname);
            
        end

        % Button pushed function: InitializeButton
        function btnInitPR670(app, event)
            
            % Validate the PsychToolbox command IOPort.
            % We did a clean install of the Psychtoolbox to make sure this
            % runs.  That included the download and the SetupPsychtoolbox
            % command.  The Setup created the correct IOPort mex file.
            str = which('IOPort','-all');
            if isempty(str)
                error('IOPort not on your path.  Check PsychToolbox installation');
            else
                [~,~,e1] = fileparts(str{1});
                [~,~,e2] = fileparts(str{2});
                if isequal(e1,'.mexw64') && isequal(e2,'.m')
                    disp('Found IOPort.mexw64')
                else
                    warndlg('Wrong order of IOPort access.  Should be .mexw64 and then .m')
                end
            end
            
            % Open the COM port to the PR670
            photometerCOM = char(serialportlist('available'));
            fprintf('Available COM port(s):    %s\n',photometerCOM);
            try
                str = PR670init(photometerCOM);
                disp(str);
            catch
                disp('Make sure the PR670 is correctly plugged in.')
                disp('The USB should be on the right side of the SCIEN lab windows machine.')
                disp('Also: check in startup.m that you did the Psychtoolbox startup correctly.')
                warning('Error initializing PR670');
                return;
            end
            
            % Set remote mode.  It seems to come up in this mode upon init
            % whether or not we send this command.
            % PR670write('PHOTO', 0);
            disp('PR670 initialized and in remote mode.')
            
            % Create a local folder with the date where the measurements
            % will be stored.  Change into that folder.
            dirName = datestr(now,'yyyy-mm-dd');
            folderName = fullfile(icalRootPath, 'local', dirName);
            if ~exist(folderName, 'dir'), mkdir(folderName); end
            cd(folderName);
            
        end

        % Button pushed function: PlotButton
        function btnPlot(app, event)
            icalPlot(app);
        end

        % Menu selected function: SaveMenu
        function fileSave(app, event)
            fname = icalSave(app,'extension','mat');
            fprintf('SPD saved in %s\n',fname);
        end

        % Menu selected function: LoadMenu
        function menuFileLoad(app, event)
            % Load a local file for plotting and perhaps uploading
            wave = prGetWave(app);
            [spd,~,comment,fname] = ieReadSpectra('',wave,0);
            
            [~,~,e] = fileparts(fname);
            
            if isempty(spd)
                return;
            elseif ~isequal(e,'.mat')
                warndlg('Load only mat-files: %s',fname);
                return;
            end
            
            app.spdGraph.UserData = spd;
            app.CommentTextArea.Value = comment;
            
            % Put the name in the app fields
            [p,n,e] = fileparts(fname);
            app.FilenameEditField.Value = [n,e];
            app.DirectoryEditField.Value = p;
            
            % Put it in the graph window
            icalPlot(app);
        end

        % Menu selected function: FlywheelMenu
        function menuActionsFlywheel(app, event)
            % Upload the current spd data to flywheel
            % We will also want a bulk convert and upload of multiple
            % files, perhaps.
            icalSave(app,'extension','csv');
        end

        % Menu selected function: SaveCSVMenu
        function menuFileSaveText(app, event)
            fname = icalSave(app,'extension','csv');
            fprintf('Saved %s\n',fname);
        end

        % Menu selected function: QuitremoteMenu
        function quitremote(app, event)
            app.icalQuit;
        end

        % Menu selected function: ExitMenu
        function exit(app, event)
            try
                app.icalQuit;
            catch ME
                warning('Error on exit');
            end
            delete(app);
        end

        % Menu selected function: EnterremoteMenu
        function enterremotemode(app, event)
             app.icalStart;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoad, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @fileSave, true);
            app.SaveMenu.Text = 'Save';
            app.SaveCSVMenu = uimenu(app.FileMenu);
            app.SaveCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveText, true);
            app.SaveCSVMenu.Text = 'Save CSV';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @exit, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ActionsMenu = uimenu(app.UIFigure);
            app.ActionsMenu.Text = 'Actions';
            app.InitializeMenu = uimenu(app.ActionsMenu);
            app.InitializeMenu.Text = 'Initialize';
            app.MeasureMenu = uimenu(app.ActionsMenu);
            app.MeasureMenu.Text = 'Measure';
            app.PlotMenu = uimenu(app.ActionsMenu);
            app.PlotMenu.Separator = 'on';
            app.PlotMenu.Text = 'Plot';
            app.EnterremoteMenu = uimenu(app.ActionsMenu);
            app.EnterremoteMenu.MenuSelectedFcn = createCallbackFcn(app, @enterremotemode, true);
            app.EnterremoteMenu.Text = 'Enter remote';
            app.QuitremoteMenu = uimenu(app.ActionsMenu);
            app.QuitremoteMenu.MenuSelectedFcn = createCallbackFcn(app, @quitremote, true);
            app.QuitremoteMenu.Text = 'Quit remote';
            app.FlywheelMenu = uimenu(app.ActionsMenu);
            app.FlywheelMenu.MenuSelectedFcn = createCallbackFcn(app, @menuActionsFlywheel, true);
            app.FlywheelMenu.Separator = 'on';
            app.FlywheelMenu.Text = 'Flywheel';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutFlywheelMenu = uimenu(app.HelpMenu);
            app.AboutFlywheelMenu.Text = 'About Flywheel';
            app.AboutPTBMenu = uimenu(app.HelpMenu);
            app.AboutPTBMenu.Text = 'About PTB';
            app.FileInformationPanel = uipanel(app.UIFigure);
            app.FileInformationPanel.Title = 'File Information';
            app.CommentTextAreaLabel = uilabel(app.FileInformationPanel);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.FileInformationPanel);
            app.FilenameEditFieldLabel = uilabel(app.FileInformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.FileInformationPanel, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.FileInformationPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FileInformationPanel, 'text');
            app.icalPR670Label = uilabel(app.UIFigure);
            app.icalPR670Label.Text = 'icalPR670';
            app.WavelengthnmPanel = uipanel(app.UIFigure);
            app.WavelengthnmPanel.Title = 'Wavelength (nm)';
            app.StartEditFieldLabel = uilabel(app.WavelengthnmPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.WavelengthnmPanel, 'numeric');
            app.StartEditField.Value = 380;
            app.StepEditFieldLabel = uilabel(app.WavelengthnmPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.WavelengthnmPanel, 'numeric');
            app.StepEditField.Value = 5;
            app.StopEditFieldLabel = uilabel(app.WavelengthnmPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.WavelengthnmPanel, 'numeric');
            app.StopEditField.Value = 780;
            app.SpectroradiometerPR670Panel = uipanel(app.UIFigure);
            app.SpectroradiometerPR670Panel.Title = 'Spectroradiometer (PR 670)';
            app.MeasureSaveButton = uibutton(app.SpectroradiometerPR670Panel, 'push');
            app.MeasureSaveButton.ButtonPushedFcn = createCallbackFcn(app, @btnMeasure, true);
            app.MeasureSaveButton.Text = 'Measure + Save';
            app.PlotButton = uibutton(app.SpectroradiometerPR670Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @btnPlot, true);
            app.PlotButton.Text = 'Plot';
            app.InitializeButton = uibutton(app.SpectroradiometerPR670Panel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @btnInitPR670, true);
            app.InitializeButton.Text = 'Initialize';
            app.RepeatsSpinnerLabel = uilabel(app.SpectroradiometerPR670Panel);
            app.RepeatsSpinnerLabel.Text = 'Repeats';
            app.RepeatsSpinner = uispinner(app.SpectroradiometerPR670Panel);
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLamp.Tooltip = {'Status of the PR670'};
            app.spdGraph = uiaxes(app.UIFigure);
            title(app.spdGraph, 'PR 670 Data')
            xlabel(app.spdGraph, 'Wave (nm)')
            ylabel(app.spdGraph, 'Energy (watts/sr/nm/m^2)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = icalPR670

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end